classdef DeepDR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeepDRUIFigure                  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ResultPanel                     matlab.ui.container.Panel
        SaveResultStatus                matlab.ui.control.Label
        ODLabel                         matlab.ui.control.Label
        EXLabel                         matlab.ui.control.Label
        SELabel                         matlab.ui.control.Label
        MALabel                         matlab.ui.control.Label
        HELabel                         matlab.ui.control.Label
        ODmmLabel                       matlab.ui.control.Label
        EXmmLabel                       matlab.ui.control.Label
        SEmmLabel                       matlab.ui.control.Label
        MAmmLabel                       matlab.ui.control.Label
        HAmmLabel                       matlab.ui.control.Label
        ODCenterField                   matlab.ui.control.EditField
        CenterEditFieldLabel            matlab.ui.control.Label
        SaveresultButton                matlab.ui.control.Button
        ODArealField                    matlab.ui.control.NumericEditField
        ArealEditFieldLabel             matlab.ui.control.Label
        EXTotalArealField               matlab.ui.control.NumericEditField
        TotalArealEditField_4Label      matlab.ui.control.Label
        EXCountField                    matlab.ui.control.NumericEditField
        CountEditField_4Label           matlab.ui.control.Label
        SETotalArealField               matlab.ui.control.NumericEditField
        TotalArealEditField_3Label      matlab.ui.control.Label
        SECountField                    matlab.ui.control.NumericEditField
        CountEditField_3Label           matlab.ui.control.Label
        MATotalArealField               matlab.ui.control.NumericEditField
        TotalArealEditField_2Label      matlab.ui.control.Label
        MACountField                    matlab.ui.control.NumericEditField
        CountEditField_2Label           matlab.ui.control.Label
        HETotalArealField               matlab.ui.control.NumericEditField
        TotalArealEditFieldLabel        matlab.ui.control.Label
        HECountField                    matlab.ui.control.NumericEditField
        CountEditFieldLabel             matlab.ui.control.Label
        SegmentedimagesettingsButtonGroup  matlab.ui.container.ButtonGroup
        EXButton                        matlab.ui.control.RadioButton
        SEButton                        matlab.ui.control.RadioButton
        MAButton                        matlab.ui.control.RadioButton
        HEButton                        matlab.ui.control.RadioButton
        ODButton                        matlab.ui.control.RadioButton
        OriginalButton                  matlab.ui.control.RadioButton
        FileselectionPanel              matlab.ui.container.Panel
        SelectImageStatusLabel          matlab.ui.control.Label
        SelectimageButton               matlab.ui.control.Button
        IoUValidationPanel              matlab.ui.container.Panel
        HardexudatesEXEditFieldLabel    matlab.ui.control.Label
        EXIoUField                      matlab.ui.control.NumericEditField
        TestEXButton                    matlab.ui.control.Button
        SoftexudatesSEEditFieldLabel    matlab.ui.control.Label
        SEIoUField                      matlab.ui.control.NumericEditField
        TestSEButton                    matlab.ui.control.Button
        HemorrhagesHEEditFieldLabel     matlab.ui.control.Label
        HEIoUField                      matlab.ui.control.NumericEditField
        TestHEButton                    matlab.ui.control.Button
        MicroaneurysmsMAEditFieldLabel  matlab.ui.control.Label
        MAIoUField                      matlab.ui.control.NumericEditField
        TestMAButton                    matlab.ui.control.Button
        ODIoUField                      matlab.ui.control.NumericEditField
        TestODButton                    matlab.ui.control.Button
        OpticDiscODEditFieldLabel       matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        SaveImagesStatus                matlab.ui.control.Label
        SaveimagesButton                matlab.ui.control.Button
        CurrentImageField               matlab.ui.control.EditField
        CurrentitemEditFieldLabel       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        imageSize = [512 512 3]

        % Segment image
        file % File name
        path % File path
        labeloverlaySEImage % Kombineret input billede og SE maske
        labeloverlayODImage % Kombineret input billede og OD maske
        labeloverlayHEImage % Kombineret input billede og HE maske
        labeloverlayMAImage % Kombineret input billede og MA maske
        labeloverlayEXImage % Kombineret input billede og EX maske
        inputImage % Brugervalgt billede
        segmentedImages % Celle array med de segmenterede læsioner

        % Segment result
        segmentationResults % Struct
        segmentLabels % Labels på læsionerne og optisk disk
    end

    methods (Access = private)

        function validationIoU(app, regionType)
            % Initialiser en waitbar for at informere brugeren om status
            wb = waitbar(0, 'Loading data...');
            waitbar(0.1, wb, 'Checking for trainingfiles and ground truth files...');

            % Mappestier for træningsbilleder og deres ground truth-filer
            trainingImagesDir = 'DiabeticRetinopathyDataset/a. Training Set';
            GroundTruthImagesDir = 'DiabeticRetinopathyDataset/a. Training Set Groundtruths';

            % Liste over træningsbilleder og ground truth-filer
            trainingImagesFiles = dir(fullfile(trainingImagesDir, '*.jpg'));
            groundTruthPattern = ['*_' regionType '.tif'];
            groundTruthImagesFiles = dir(fullfile(GroundTruthImagesDir, groundTruthPattern));

            % Opretter lister over filnavne uden stier og uden filudvidelser
            imageFileNames = erase({trainingImagesFiles.name}, '.jpg');
            groundTruthPattern_ = ['_' regionType '.tif'];
            annotationFileNames = erase({groundTruthImagesFiles.name}, groundTruthPattern_);

            % Finder de fælles filnavne mellem billeder og annotationsfiler
            [~, imageIdx, annotationIdx] = intersect(imageFileNames, annotationFileNames);

            % Filtrer imageFiles og annotationFiles så de kun indeholder de fælles filer
            trainingImagesFiles =  trainingImagesFiles(imageIdx);
            groundTruthImagesFiles = groundTruthImagesFiles(annotationIdx);

            % Tjekker for træningsbilleder og ground truth-filer
            if isempty(trainingImagesFiles) || isempty(groundTruthImagesFiles)
                uialert(app.DeepDRUIFigure, error.message, 'No training images or ground truth files found. Check path and filenames.');  % Show a error message
            end

            % Tjekker antallet af træningsbilleder passer med antallet af ground truth-filer
            if numel(trainingImagesFiles) ~= numel(groundTruthImagesFiles)
                uialert(app.DeepDRUIFigure, error.message, 'The number of training images does not match the number of ground truth files.');
            end

            % Definer klassernes navne og labels
            if strcmp(regionType, 'EX')
                classNames = ["Background", 'Exudates'];
            else
                classNames = ["Background", regionType];
            end
            labelIDs = [0 1]; % 0 for baggrund, 1 for læsion typen
            numClasses = numel(classNames); % Antal af klasser

            % Initialiser metrikker
            iouValues = zeros(numel(trainingImagesFiles), numClasses);

            % Indlæs det trænede netværk for den valgte læsion
            networkFile = ['trainedNetwork_' regionType '.mat'];
            try
                loadNetwork = load(networkFile);
                net = loadNetwork.net;
            catch
                uialert(app.DeepDRUIFigure, error.message, ['The network file "' networkFile '" was not found. ' ...
                    'Ensure that the file is in the correct folder and try again.']);
            end

            % Opdater waitbar status
            waitbar(0.5, wb, 'Segment trainingsfiles...');

            % Finder antallet af træningsbilleder
            numFiles = numel(trainingImagesFiles);

            % Iterer gennem træningsbillederne
            for idx = 1:numel(trainingImagesFiles)

                % Indlæser træningsbillede
                trainingImage = imread(fullfile(trainingImagesFiles(idx).folder, trainingImagesFiles(idx).name));

                % Juster størrelsen på træningsbilledet til kravet for
                % netværket
                trainingImageResized = imresize(trainingImage, app.imageSize(1:2));

                % Indlæser tilhørende ground truth billede
                groundTruth = imread(fullfile(groundTruthImagesFiles(idx).folder, groundTruthImagesFiles(idx).name));

                % Juster størrelsen på ground truth billedet til kravet for
                % netværket
                groundTruthResized = imresize(groundTruth, app.imageSize(1:2), 'nearest');

                % Kategoriser ground truth med den ændrede billede
                % størrelse, label IDs og klassenavne
                groundTruthCategorical = categorical(groundTruthResized, labelIDs, classNames);

                % Segmenter træningsbilledet med netværket
                C = semanticseg(trainingImageResized, net);

                % Beregner IoU ud fra det segmenteret træningsbillede og
                % tilhørende ground truth billede
                iou = jaccard(C, groundTruthCategorical);
                iouValues(idx, :) = iou;

                % Opdater waitbar status på antallet af billeder tilbage
                waitbar(0.5 + 0.4 * (idx / numFiles), wb, sprintf('Segment %d of %d images...', idx, numFiles));
            end

            % Opdater waitbar status
            waitbar(0.9, wb, ['Calculating IoU for ' regionType '...']);

            % Beregner gennemsnitlig IoU for hver klasse
            meanIoU = mean(iouValues, 1);

            % Angiver gennemsnitlig IoU til the rigtige felt
            switch regionType
                case 'OD'
                    app.ODIoUField.Value = round(meanIoU(2), 2);
                case 'SE'
                    app.SEIoUField.Value = round(meanIoU(2), 2);
                case 'EX'
                    app.EXIoUField.Value = round(meanIoU(2), 2);
                case 'HE'
                    app.HEIoUField.Value = round(meanIoU(2), 2);
                case 'MA'
                    app.MAIoUField.Value = round(meanIoU(2), 2);
                otherwise
                    error('Unknown region type.');
            end

            % Indstiller waitbar til fuldført
            waitbar(1, wb, 'Complete!');
            pause(0.5); % Pauser waitbar så brugeren kan se, at det er fuldført
            close(wb); % Lukker waitbar
        end

        
        function openFile(app)
            [app.file,app.path] = uigetfile('*.jpg');
            if isequal(app.file,0)
                return
            else
                % Opdatere feltet for the nuværende billede
                app.CurrentImageField.Value = app.file;
                try
                    % Indlæser og viser the valgte billede
                    showInputImage(app);
                catch e
                    % Giver en feedback til brugeren om at der er sket en
                    % fejl
                    uialert(app.DeepDRUIFigure, 'Failed to load the image. Please try another image.', 'Image Load Error', 'Icon', 'error');
                end
            end
        end
        
        function showInputImage(app)
            % Indlæser the valgte billede
            app.inputImage = imread(fullfile(app.path,app.file));
            % Viser the valgte billede i GUI
            imshow(app.inputImage,'Parent',app.UIAxes);
            app.UIAxes.Title.String = "Original Image";
        end
        
        function selectedImage(app)
            % Henter den seneste valgte knap
            selectedButton = app.SegmentedimagesettingsButtonGroup.SelectedObject;

            % Baseret på den valgte knap opdateres billedet i GUI
            switch(selectedButton.Text)
                case "Original"
                    imshow(app.inputImage,'Parent',app.UIAxes);
                    app.UIAxes.Title.String = "Original Image";
                case "SE"
                    imshow(app.labeloverlaySEImage, 'Parent',app.UIAxes);
                    app.UIAxes.Title.String = "Segmented for Soft Exudates";
                case "OD"
                    imshow(app.labeloverlayODImage, 'Parent',app.UIAxes);
                    app.UIAxes.Title.String = "Segmented for Optic Disc";
                case "HE"
                    imshow(app.labeloverlayHEImage, 'Parent',app.UIAxes);
                    app.UIAxes.Title.String = "Segmented for Hermorrhages";
                case "MA"
                    imshow(app.labeloverlayMAImage, 'Parent',app.UIAxes);
                    app.UIAxes.Title.String = "Segmented for Microaneurysms";
                case "EX"
                    imshow(app.labeloverlayEXImage, 'Parent',app.UIAxes);
                    app.UIAxes.Title.String = "Segmented for Hard Exudates";
            end
        end
        
        function segmentImage(app)
            % Gemmer den oprindelige størrelse på det valgte billede
            originalSize = size(app.inputImage);

            % Ændre størrelsen på billedet ud fra netværkskravet
            imageResized = imresize(app.inputImage, app.imageSize(1:2));

            % Definer Filnavne på det trænede netværk samt labels
            networkFiles = {'trainedNetwork_SE.mat', 'trainedNetwork_OD.mat', 'trainedNetwork_HE.mat', 'trainedNetwork_MA.mat', 'trainedNetwork_EX.mat'};
            app.segmentLabels = {'SE', 'OD', 'HE', 'MA', 'Exudates'};

            % Initialiser en celle array til at holde de segmenterede billeder
            app.segmentedImages = cell(1, length(networkFiles));

            % Giver en feedback på segmenteringen til brugeren
            app.SelectImageStatusLabel.Text = 'The segment is starting...';
            drawnow; % Sørger for at opdatere GUI

            % Looper gennem hvert netværk og udfør segmentering
            for i = 1:length(networkFiles)

                % Viser en status på segmenteringen til brugeren
                app.SelectImageStatusLabel.Text = ['Segment ', app.segmentLabels{i} '...'];
                drawnow;

                % Indlæser det gemte netværk
                try
                    loadNetwork = load(networkFiles{i});
                    net = loadNetwork.net;
                catch
                    uialert(app.DeepDRUIFigure, error.message, ['The network file "' networkFiles{i} '" was not found. ' ...
                        'Ensure that the file is in the correct folder and try again.']);
                end

                % Segmenter det justerede billede med netværket
                app.segmentedImages{i} = semanticseg(imageResized, net);
            end

            % Ændre det segmentede billede størrelse til den oprindelige
            % størrelse
            for i = 1:length(app.segmentedImages)
                app.segmentedImages{i} = imresize(app.segmentedImages{i}, originalSize(1:2), 'nearest');
            end

            % Kombiner det valgte billede med det segmenterede billede for
            % hver læsion
            app.labeloverlaySEImage = labeloverlay(app.inputImage, app.segmentedImages{1}, 'Transparency', 0.5);
            app.labeloverlayODImage = labeloverlay(app.inputImage, app.segmentedImages{2}, 'Transparency', 0.5);
            app.labeloverlayHEImage = labeloverlay(app.inputImage, app.segmentedImages{3}, 'Transparency', 0.5);
            app.labeloverlayMAImage = labeloverlay(app.inputImage, app.segmentedImages{4}, 'Transparency', 0.5);
            app.labeloverlayEXImage = labeloverlay(app.inputImage, app.segmentedImages{5}, 'Transparency', 0.5);
        end
        
        function saveSegmentedImages(app)
            % Lad brugeren vælge en filplacering og navn
            [app.file, app.path] = uiputfile({'*.tif', 'TIFF image'; '*.mat', 'MAT-file'}, 'Save Segmented Image');
            if isequal(app.file, 0) || isequal(app.path, 0)
                return;
            end

            % Kombiner sti og filnavn
            fullPath = fullfile(app.path, app.file);

            % Gem hver segmenteret billed-label som en TIFF-fil eller MAT-fil
            if endsWith(app.file, '.tif')
                [~, name, ext] = fileparts(fullPath);
                imwrite(app.labeloverlaySEImage, fullfile(app.path, [name '_SE' ext]));
                imwrite(app.labeloverlayODImage, fullfile(app.path, [name '_OD' ext]));
                imwrite(app.labeloverlayHEImage, fullfile(app.path, [name '_HE' ext]));
                imwrite(app.labeloverlayMAImage, fullfile(app.path, [name '_MA' ext]));
                imwrite(app.labeloverlayEXImage, fullfile(app.path, [name '_EX' ext]));

                % Feedback til brugeren om billederne blev gemt
                app.SaveImagesStatus.Text = 'Saved!';
            elseif endsWith(app.file, '.mat')
                % Gem segmenteringsresultater som MAT-fil
                save(fullPath, 'app.segmentedSEImage', 'app.segmentedODImage', 'app.segmentedHEImage', 'app.segmentedMAImage', 'app.segmentedEXImage');

                % Feedback til brugeren om billederne blev gemt
                app.SaveImagesStatus.Text = 'Saved!';
            end
        end
        
        function analyzeResult(app)
            pixelSize = 0.003; % Har antaget en værdi, men skal opdates med den rigtige opløsning (i millimeter pr. pixel)
            pixelArea = pixelSize^2; % Beregner arealet af en pixel i kvadratmillimeter

            % Giver feedback til brugeren om, at resultaterne bliver beregnet
            app.SelectImageStatusLabel.Text = 'Calculate the result...';
            drawnow;

            % Initialiser en struktur til at gemme segmenteringsresultaterne
            app.segmentationResults = struct();


            % Loop gennem hver type af segmenteringslabels
            for i = 1:length(app.segmentLabels)
                label = app.segmentLabels{i}; % Henter den aktuelle label
                segmentedImage = app.segmentedImages{i}; % Henter det segmenterede billede for den aktuelle label
                BW = segmentedImage == label; % Opreter en binær maske for den aktuelle label

                if strcmp(label, 'OD') % Hvis label er 'OD'
                    % Beregn centrum og areal for OD
                    stats = regionprops(BW, 'Centroid', 'Area');
                    if ~isempty(stats)
                        OD_Centroid = stats.Centroid * pixelSize; % Konverter centrum til millimeter
                        OD_Area = stats.Area * pixelArea; % Konverter arealet til kvadratmillimeter
                        app.segmentationResults.OD = struct('Centroid', OD_Centroid, 'Area', OD_Area);
                    else
                        app.segmentationResults.OD = struct('Centroid', [], 'Area', 0); % Hvis ingen OD fundet, sæt default værdien
                    end
                else
                    % Beregner antal og samlet areal for de andre læsioner
                    stats = regionprops(BW, 'Area');
                    count = numel(stats); % Antallet for den givne læsion
                    totalArea = sum([stats.Area]) * pixelArea; % Samlet areal for den givne læsion konverteret til kvadratmillimeter
                    app.segmentationResults.(label) = struct('Count', count, 'TotalArea', totalArea);
                end
            end

            % Viser resultaterne for hver læsion i GUI
            app.ODArealField.Value = round(app.segmentationResults.OD.Area, 2);
            app.ODCenterField.Value = sprintf('(%.2f, %.2f)', app.segmentationResults.OD.Centroid(1), app.segmentationResults.OD.Centroid(2));

            app.SETotalArealField.Value = round(app.segmentationResults.SE.TotalArea, 2);
            app.SECountField.Value = app.segmentationResults.SE.Count;

            app.HETotalArealField.Value = round(app.segmentationResults.HE.TotalArea, 2);
            app.HECountField.Value = app.segmentationResults.HE.Count;

            app.MATotalArealField.Value = round(app.segmentationResults.MA.TotalArea, 2);
            app.MACountField.Value = app.segmentationResults.MA.Count;

            app.EXTotalArealField.Value = round(app.segmentationResults.Exudates.TotalArea, 2);
            app.EXCountField.Value = app.segmentationResults.Exudates.Count;

            % Feedback på segmenteringen til brugeren
            app.SelectImageStatusLabel.Text = 'Success!';
        end
        
        function saveResult(app)
            % Gemmer resultatet som en enkelt tekst fil
            [app.file,app.path] = uiputfile({'*.txt','TXT-files'},'Save result as');
            if app.file ~= 0
                output{1} = app.file;
                output{2} = round(app.segmentationResults.OD.Area, 2);
                output{3} = sprintf('(%.2f, %.2f)', app.segmentationResults.OD.Centroid(1), app.segmentationResults.OD.Centroid(2));
                output{4} = round(app.segmentationResults.SE.TotalArea, 2);
                output{5} = app.segmentationResults.SE.Count;
                output{6} = round(app.segmentationResults.HE.TotalArea, 2);
                output{7} = app.segmentationResults.HE.Count;
                output{8} = round(app.segmentationResults.MA.TotalArea, 2);
                output{9} = app.segmentationResults.MA.Count;
                output{10} = round(app.segmentationResults.Exudates.TotalArea, 2);
                output{11} = app.segmentationResults.Exudates.Count;
                
                % Opretter en tabel fra celle arrayen med navngivne kolonner
                outputTable = cell2table(output,'VariableNames',{'Filename','OD Area','OD Center', 'SE Total Area', 'SE Count', 'HE Total Area', 'HE Count', 'MA Total Area', 'MA Count', 'EX Total Area', 'EX Count'});
                
                % Gemmer tabellen til en CSV-fil med semikolon som delimiter
                writetable(outputTable,fullfile(app.path,app.file),'Delimiter',';');

                % Feedback til brugeren om resultatet blev gemt
                app.SaveResultStatus.Text = 'Saved!';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: TestODButton
        function TestODButtonPushed(app, event)
            validationIoU(app, 'OD');
        end

        % Button pushed function: TestSEButton
        function TestSEButtonPushed(app, event)
            validationIoU(app, 'SE');
        end

        % Button pushed function: SelectimageButton
        function SelectimageButtonPushed(app, event)
            % Deaktiver UI elements
            app.OriginalButton.Enable = "off";
            app.ODButton.Enable = "off";
            app.MAButton.Enable = "off";
            app.HEButton.Enable = "off";
            app.SEButton.Enable = "off";
            app.EXButton.Enable = "off";
            app.SaveresultButton.Enable = "off";
            app.SaveimagesButton.Enable = "off";

            % Nulstiller UI labels
            app.SaveResultStatus.Text = "";
            app.SaveImagesStatus.Text = "";
            app.SelectImageStatusLabel.Text = "";
            
            openFile(app);
            segmentImage(app);
            analyzeResult(app);

            % Enable UI elements
            app.OriginalButton.Enable = "on";
            app.ODButton.Enable = "on";
            app.MAButton.Enable = "on";
            app.HEButton.Enable = "on";
            app.SEButton.Enable = "on";
            app.EXButton.Enable = "on";
            app.SaveresultButton.Enable = "on";
            app.SaveimagesButton.Enable = "on";
        end

        % Selection changed function: SegmentedimagesettingsButtonGroup
        function SegmentedimagesettingsButtonGroupSelectionChanged(app, event)
            selectedImage(app);
        end

        % Button pushed function: TestHEButton
        function TestHEButtonPushed(app, event)
            validationIoU(app, 'HE');
        end

        % Button pushed function: TestMAButton
        function TestMAButtonPushed(app, event)
            validationIoU(app, 'MA');
        end

        % Button pushed function: TestEXButton
        function TestEXButtonPushed(app, event)
            validationIoU(app, 'EX');
        end

        % Button pushed function: SaveresultButton
        function SaveresultButtonPushed(app, event)
             saveResult(app);
        end

        % Button pushed function: SaveimagesButton
        function SaveimagesButtonPushed(app, event)
            saveSegmentedImages(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DeepDRUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {621, 621};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {304, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeepDRUIFigure = uifigure('Visible', 'off');
            app.DeepDRUIFigure.Name = 'DeepDR';
            app.DeepDRUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.DeepDRUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.IoUValidationPanel = uipanel(app.LeftPanel);
            app.IoUValidationPanel.Title = 'IoU Validation';
            app.OpticDiscODEditFieldLabel = uilabel(app.IoUValidationPanel);
            app.OpticDiscODEditFieldLabel.Text = 'Optic Disc (OD):';
            app.TestODButton = uibutton(app.IoUValidationPanel, 'push');
            app.TestODButton.ButtonPushedFcn = createCallbackFcn(app, @TestODButtonPushed, true);
            app.TestODButton.Text = 'Test';
            app.ODIoUField = uieditfield(app.IoUValidationPanel, 'numeric');
            app.ODIoUField.Editable = 'off';
            app.TestMAButton = uibutton(app.IoUValidationPanel, 'push');
            app.TestMAButton.ButtonPushedFcn = createCallbackFcn(app, @TestMAButtonPushed, true);
            app.TestMAButton.Text = 'Test';
            app.MAIoUField = uieditfield(app.IoUValidationPanel, 'numeric');
            app.MAIoUField.Editable = 'off';
            app.MicroaneurysmsMAEditFieldLabel = uilabel(app.IoUValidationPanel);
            app.MicroaneurysmsMAEditFieldLabel.Text = 'Microaneurysms (MA):';
            app.TestHEButton = uibutton(app.IoUValidationPanel, 'push');
            app.TestHEButton.ButtonPushedFcn = createCallbackFcn(app, @TestHEButtonPushed, true);
            app.TestHEButton.Text = 'Test';
            app.HEIoUField = uieditfield(app.IoUValidationPanel, 'numeric');
            app.HEIoUField.Editable = 'off';
            app.HemorrhagesHEEditFieldLabel = uilabel(app.IoUValidationPanel);
            app.HemorrhagesHEEditFieldLabel.Text = 'Hemorrhages (HE):';
            app.TestSEButton = uibutton(app.IoUValidationPanel, 'push');
            app.TestSEButton.ButtonPushedFcn = createCallbackFcn(app, @TestSEButtonPushed, true);
            app.TestSEButton.Text = 'Test';
            app.SEIoUField = uieditfield(app.IoUValidationPanel, 'numeric');
            app.SEIoUField.Editable = 'off';
            app.SoftexudatesSEEditFieldLabel = uilabel(app.IoUValidationPanel);
            app.SoftexudatesSEEditFieldLabel.Text = 'Soft exudates (SE):';
            app.TestEXButton = uibutton(app.IoUValidationPanel, 'push');
            app.TestEXButton.ButtonPushedFcn = createCallbackFcn(app, @TestEXButtonPushed, true);
            app.TestEXButton.Text = 'Test';
            app.EXIoUField = uieditfield(app.IoUValidationPanel, 'numeric');
            app.EXIoUField.Editable = 'off';
            app.HardexudatesEXEditFieldLabel = uilabel(app.IoUValidationPanel);
            app.HardexudatesEXEditFieldLabel.Text = 'Hard exudates (EX):';
            app.FileselectionPanel = uipanel(app.LeftPanel);
            app.FileselectionPanel.Title = 'File selection';
            app.SelectimageButton = uibutton(app.FileselectionPanel, 'push');
            app.SelectimageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectimageButtonPushed, true);
            app.SelectimageButton.Text = 'Select image';
            app.SelectImageStatusLabel = uilabel(app.FileselectionPanel);
            app.SelectImageStatusLabel.Text = '';
            app.SegmentedimagesettingsButtonGroup = uibuttongroup(app.LeftPanel);
            app.SegmentedimagesettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SegmentedimagesettingsButtonGroupSelectionChanged, true);
            app.SegmentedimagesettingsButtonGroup.Title = 'Segmented image settings';
            app.OriginalButton = uiradiobutton(app.SegmentedimagesettingsButtonGroup);
            app.OriginalButton.Enable = 'off';
            app.OriginalButton.Text = 'Original';
            app.OriginalButton.Value = true;
            app.ODButton = uiradiobutton(app.SegmentedimagesettingsButtonGroup);
            app.ODButton.Enable = 'off';
            app.ODButton.Text = 'OD';
            app.HEButton = uiradiobutton(app.SegmentedimagesettingsButtonGroup);
            app.HEButton.Enable = 'off';
            app.HEButton.Text = 'HE';
            app.MAButton = uiradiobutton(app.SegmentedimagesettingsButtonGroup);
            app.MAButton.Enable = 'off';
            app.MAButton.Text = 'MA';
            app.SEButton = uiradiobutton(app.SegmentedimagesettingsButtonGroup);
            app.SEButton.Enable = 'off';
            app.SEButton.Text = 'SE';
            app.EXButton = uiradiobutton(app.SegmentedimagesettingsButtonGroup);
            app.EXButton.Enable = 'off';
            app.EXButton.Text = 'EX';
            app.ResultPanel = uipanel(app.LeftPanel);
            app.ResultPanel.Title = 'Result';
            app.CountEditFieldLabel = uilabel(app.ResultPanel);
            app.CountEditFieldLabel.Text = ' Count:';
            app.HECountField = uieditfield(app.ResultPanel, 'numeric');
            app.HECountField.Editable = 'off';
            app.TotalArealEditFieldLabel = uilabel(app.ResultPanel);
            app.TotalArealEditFieldLabel.Text = 'Total Areal:';
            app.HETotalArealField = uieditfield(app.ResultPanel, 'numeric');
            app.HETotalArealField.Editable = 'off';
            app.CountEditField_2Label = uilabel(app.ResultPanel);
            app.CountEditField_2Label.Text = ' Count:';
            app.MACountField = uieditfield(app.ResultPanel, 'numeric');
            app.MACountField.Editable = 'off';
            app.TotalArealEditField_2Label = uilabel(app.ResultPanel);
            app.TotalArealEditField_2Label.Text = 'Total Areal:';
            app.MATotalArealField = uieditfield(app.ResultPanel, 'numeric');
            app.MATotalArealField.Editable = 'off';
            app.CountEditField_3Label = uilabel(app.ResultPanel);
            app.CountEditField_3Label.Text = ' Count:';
            app.SECountField = uieditfield(app.ResultPanel, 'numeric');
            app.SECountField.Editable = 'off';
            app.TotalArealEditField_3Label = uilabel(app.ResultPanel);
            app.TotalArealEditField_3Label.Text = 'Total Areal:';
            app.SETotalArealField = uieditfield(app.ResultPanel, 'numeric');
            app.SETotalArealField.Editable = 'off';
            app.CountEditField_4Label = uilabel(app.ResultPanel);
            app.CountEditField_4Label.Text = ' Count:';
            app.EXCountField = uieditfield(app.ResultPanel, 'numeric');
            app.EXCountField.Editable = 'off';
            app.TotalArealEditField_4Label = uilabel(app.ResultPanel);
            app.TotalArealEditField_4Label.Text = 'Total Areal:';
            app.EXTotalArealField = uieditfield(app.ResultPanel, 'numeric');
            app.EXTotalArealField.Editable = 'off';
            app.ArealEditFieldLabel = uilabel(app.ResultPanel);
            app.ArealEditFieldLabel.Text = 'Areal:';
            app.ODArealField = uieditfield(app.ResultPanel, 'numeric');
            app.ODArealField.Editable = 'off';
            app.SaveresultButton = uibutton(app.ResultPanel, 'push');
            app.SaveresultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultButtonPushed, true);
            app.SaveresultButton.Enable = 'off';
            app.SaveresultButton.Text = 'Save result';
            app.CenterEditFieldLabel = uilabel(app.ResultPanel);
            app.CenterEditFieldLabel.Text = ' Center:';
            app.ODCenterField = uieditfield(app.ResultPanel, 'text');
            app.ODCenterField.Editable = 'off';
            app.HAmmLabel = uilabel(app.ResultPanel);
            app.HAmmLabel.Text = 'mm²';
            app.MAmmLabel = uilabel(app.ResultPanel);
            app.MAmmLabel.Text = 'mm²';
            app.SEmmLabel = uilabel(app.ResultPanel);
            app.SEmmLabel.Text = 'mm²';
            app.EXmmLabel = uilabel(app.ResultPanel);
            app.EXmmLabel.Text = 'mm²';
            app.ODmmLabel = uilabel(app.ResultPanel);
            app.ODmmLabel.Text = 'mm²';
            app.HELabel = uilabel(app.ResultPanel);
            app.HELabel.Text = 'HE';
            app.MALabel = uilabel(app.ResultPanel);
            app.MALabel.Text = 'MA';
            app.SELabel = uilabel(app.ResultPanel);
            app.SELabel.Text = 'SE';
            app.EXLabel = uilabel(app.ResultPanel);
            app.EXLabel.Text = 'EX';
            app.ODLabel = uilabel(app.ResultPanel);
            app.ODLabel.Text = 'OD';
            app.SaveResultStatus = uilabel(app.ResultPanel);
            app.SaveResultStatus.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.CurrentitemEditFieldLabel = uilabel(app.RightPanel);
            app.CurrentitemEditFieldLabel.Text = 'Current item:';
            app.CurrentImageField = uieditfield(app.RightPanel, 'text');
            app.CurrentImageField.Editable = 'off';
            app.SaveimagesButton = uibutton(app.RightPanel, 'push');
            app.SaveimagesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimagesButtonPushed, true);
            app.SaveimagesButton.Enable = 'off';
            app.SaveimagesButton.Text = 'Save images';
            app.SaveImagesStatus = uilabel(app.RightPanel);
            app.SaveImagesStatus.Text = '';
            % Show the figure after all components are created
            app.DeepDRUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeepDR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DeepDRUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeepDRUIFigure)
        end
    end
end